classdef Omar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageProcessingFilterUIFigure  matlab.ui.Figure
        AverageButton                  matlab.ui.control.Button
        GaussianButton                 matlab.ui.control.Button
        MedianButton                   matlab.ui.control.Button
        ComplementButton               matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        DivideButton                   matlab.ui.control.Button
        MultiplyButton                 matlab.ui.control.Button
        SobelButton                    matlab.ui.control.Button
        PrewittButton                  matlab.ui.control.Button
        HistogramButton                matlab.ui.control.Button
        LaplacianButton                matlab.ui.control.Button
        AdjustButton                   matlab.ui.control.Button
        ImageProcessingFilterLabel     matlab.ui.control.Label
        UIAxes_1                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UploadImageButton              matlab.ui.control.Button
        CLOSEButton                    matlab.ui.control.Button
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
            m = imread('C:\Users\public\myphoto.jpg');
            f = fspecial('average');
            mf = imfilter(m,f);
            imshow(mf,'Parent',app.UIAxes_2);
        end

        % Button pushed function: MultiplyButton
        function MultiplyButtonPushed(app, event)
            m = imread('C:\Users\public\myphoto.jpg');
            mf = immultiply(m,10);
            imshow(mf,'Parent',app.UIAxes_2);
        end

        % Button pushed function: MedianButton
        function MedianButtonPushed(app, event)
            m = imread('C:\Users\public\myphoto.jpg');
            mf = medfilt2(m);
            imshow(mf,'Parent',app.UIAxes_2);
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
            m = imread('C:\Users\public\myphoto.jpg');
            f = fspecial('gaussian');
            mf = imfilter(m,f);
            imshow(mf,'Parent',app.UIAxes_2);
        end

        % Button pushed function: DivideButton
        function DivideButtonPushed(app, event)
            m = imread('C:\Users\public\myphoto.jpg');
            mf = imdivide(m,2);
            imshow(mf,'Parent',app.UIAxes_2);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            m = imread('C:\Users\public\myphoto.jpg');
            mf = imadd(m , 50 );
            imshow(mf,'Parent',app.UIAxes_2);
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            m = imread('C:\Users\public\myphoto.jpg');
            mf = edge(m , 'sobel' );
            imshow(mf,'Parent',app.UIAxes_2);
        end

        % Button pushed function: ComplementButton
        function ComplementButtonPushed(app, event)
            m = imread('C:\Users\public\myphoto.jpg');
            mf = imcomplement(m);
            imshow(mf,'Parent',app.UIAxes_2);
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            m= imread('C:\Users\public\myphoto.jpg');
            mf = edge(m , 'prewitt' );
            imshow(mf,'Parent',app.UIAxes_2);
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            m = imread('C:\Users\Public\myphoto.jpg');
            mf = histeq(m);
            imshow(mf,'Parent',app.UIAxes_2);
        end

        % Button pushed function: LaplacianButton
        function LaplacianButtonPushed(app, event)
            m = imread('C:\Users\public\myphoto.jpg');
            f = fspecial('laplacian' , 0);
            mf = filter2(f,m);
            imshow(mf,'Parent',app.UIAxes_2);
        end

        % Button pushed function: AdjustButton
        function AdjustButtonPushed(app, event)
            m = imread('C:\Users\public\myphoto.jpg');
            mf = imadjust(m);
            imshow(mf,'Parent',app.UIAxes_2);
        end

        % Callback function
        function CannyButtonPushed(app, event)
            m= imread('C:\Users\public\myphoto.jpg');
            mf = edge(m , 'Canny' );
            imshow(mf,'Parent',app.UIAxes_2);
        end

        % Callback function: ImageProcessingFilterUIFigure, 
        % UploadImageButton
        function UploadWhiteBlackImageButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
            fullname = [filepath, filename];
            ImageFile = imread(fullname);
            I = rgb2gray(ImageFile);
            baseFileName = 'myphoto.jpg';
            fullFileName = fullfile('C:\Users\public', baseFileName);
            A = I ;
            imwrite(A,fullFileName);
            imshow(ImageFile,'Parent',app.UIAxes_1);
        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            closereq(); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageProcessingFilterUIFigure = uifigure;
            app.ImageProcessingFilterUIFigure.Name = 'Image  Processing Filter';
            app.ImageProcessingFilterUIFigure.CloseRequestFcn = createCallbackFcn(app, @UploadWhiteBlackImageButtonPushed, true);
            app.AverageButton = uibutton(app.ImageProcessingFilterUIFigure, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.GaussianButton = uibutton(app.ImageProcessingFilterUIFigure, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.MedianButton = uibutton(app.ImageProcessingFilterUIFigure, 'push');
            app.MedianButton.ButtonPushedFcn = createCallbackFcn(app, @MedianButtonPushed, true);
            app.MedianButton.Text = 'Median';
            app.ComplementButton = uibutton(app.ImageProcessingFilterUIFigure, 'push');
            app.ComplementButton.ButtonPushedFcn = createCallbackFcn(app, @ComplementButtonPushed, true);
            app.ComplementButton.Text = 'Complement';
            app.AddButton = uibutton(app.ImageProcessingFilterUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DivideButton = uibutton(app.ImageProcessingFilterUIFigure, 'push');
            app.DivideButton.ButtonPushedFcn = createCallbackFcn(app, @DivideButtonPushed, true);
            app.DivideButton.Text = 'Divide';
            app.MultiplyButton = uibutton(app.ImageProcessingFilterUIFigure, 'push');
            app.MultiplyButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyButtonPushed, true);
            app.MultiplyButton.Text = 'Multiply';
            app.SobelButton = uibutton(app.ImageProcessingFilterUIFigure, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.PrewittButton = uibutton(app.ImageProcessingFilterUIFigure, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.HistogramButton = uibutton(app.ImageProcessingFilterUIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.LaplacianButton = uibutton(app.ImageProcessingFilterUIFigure, 'push');
            app.LaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButtonPushed, true);
            app.LaplacianButton.Text = 'Laplacian';
            app.AdjustButton = uibutton(app.ImageProcessingFilterUIFigure, 'push');
            app.AdjustButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustButtonPushed, true);
            app.AdjustButton.Text = 'Adjust';
            app.ImageProcessingFilterLabel = uilabel(app.ImageProcessingFilterUIFigure);
            app.ImageProcessingFilterLabel.Text = 'Image  Processing Filter';
            app.UIAxes_1 = uiaxes(app.ImageProcessingFilterUIFigure);
            title(app.UIAxes_1, 'Original Image')
            app.UIAxes_2 = uiaxes(app.ImageProcessingFilterUIFigure);
            title(app.UIAxes_2, 'Converted Image')
            app.UploadImageButton = uibutton(app.ImageProcessingFilterUIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadWhiteBlackImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.CLOSEButton = uibutton(app.ImageProcessingFilterUIFigure, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Text = 'CLOSE';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Omar

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageProcessingFilterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageProcessingFilterUIFigure)
        end
    end
end